classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        MenuPanel          matlab.ui.container.Panel
        OpenImageButton    matlab.ui.control.Button
        TransformButton    matlab.ui.control.Button
        ExitButton         matlab.ui.control.Button
        NofilechosenLabel  matlab.ui.control.Label
        ImagePanel         matlab.ui.container.Panel
        ImageInput         matlab.ui.control.Image
    end

    
    properties (Access = private)
        im = [];
        imName='';
        
        adaptiveIm=[];
        anisoIm=[];
        
        
    end
    methods (Access = private)
        function confirmClose(app, ~, event)
            % Determine which dialog button the user clicked
            answer = event.SelectedOption;
            
            % Close the app if the user clicks OK
            if strcmp(answer,'OK')
                delete(app);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            
            [imName, imPath] = uigetfile({'*.jpg'},'File Selector');
            
            if isequal(imName,0)
                disp('User selected Cancel');
            else
                fullpath = strcat(imPath,imName);
                app.im= imread(fullpath);
                
                if isequal(size(app.im),[500 500 3])
                    [~, app.imName, ~]=fileparts(fullpath);
                    app.ImageInput.ImageSource = fullpath;
                    app.NofilechosenLabel.Text = imName;
                    app.TransformButton.Enable = true;
                    
                    return
                end
                message = sprintf('Please select a color image of size 500 x 500 pixels.');
                uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
            end
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            adaptiveObj=AdaptiveTMethod(app.imName,app.im);
            app.adaptiveIm=adaptiveObj.getIm();
            
            anisotropicObj=AnisotropicDMethod(app.imName,app.adaptiveIm);
            app.anisoIm=anisotropicObj.getIm();
            
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            msg = 'Are you sure you want to close?';
            uiconfirm(app.UIFigure,msg,'Confirm Close','CloseFcn',@app.confirmClose);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Transform Face Image to Text Image';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Tooltip = {'ImageInput'};
            app.Panel.Title = 'Transform Face Image to Text Image';
            app.MenuPanel = uipanel(app.Panel);
            app.MenuPanel.Title = 'Menu';
            app.OpenImageButton = uibutton(app.MenuPanel, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.TransformButton = uibutton(app.MenuPanel, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Enable = 'off';
            app.TransformButton.Text = 'Transform';
            app.ExitButton = uibutton(app.MenuPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.NofilechosenLabel = uilabel(app.MenuPanel);
            app.NofilechosenLabel.Text = '  No file chosen  ';
            app.ImagePanel = uipanel(app.Panel);
            app.ImagePanel.Title = 'Image';
            app.ImageInput = uiimage(app.ImagePanel);
            app.ImageInput.Tooltip = {'ImageInput'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end